classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BalanceCAOUIFigure              matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        MainTabGroup                    matlab.ui.container.TabGroup
        EditeurdesimulationTab          matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        ParamtresdusimulateurPanel      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ChargerparamtrespardfautButton  matlab.ui.control.Button
        SauvegarderButton               matlab.ui.control.Button
        ChargerparamtrespersonnalissButton  matlab.ui.control.Button
        AmplificateurdepuissanceLabel   matlab.ui.control.Label
        GainEditFieldLabel              matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
        EntresPanel                     matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        MassedposegEditFieldLabel       matlab.ui.control.Label
        MassedposegEditField            matlab.ui.control.NumericEditField
        ChangementDeMasseCheckBox       matlab.ui.control.CheckBox
        NouvellemassegEditFieldLabel    matlab.ui.control.Label
        NouvelleMasseEditField          matlab.ui.control.NumericEditField
        MomentdarrivedelamassesEditFieldLabel  matlab.ui.control.Label
        MomentArriveeEditField          matlab.ui.control.NumericEditField
        SimulationPanel                 matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        LancerlasimulationButton        matlab.ui.control.Button
        DuredelasimulationsEditField_2Label  matlab.ui.control.Label
        DuredelasimulationsEditField_2  matlab.ui.control.EditField
        BalayagedeparamtresPanel        matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        ParamtrebalayDropDown_2Label    matlab.ui.control.Label
        ParametreBalayeDropDown         matlab.ui.control.DropDown
        BalayageButton                  matlab.ui.control.Button
        OptionsdaffichagePanel          matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        ModeDropDown_2Label             matlab.ui.control.Label
        ModeDropDown_2                  matlab.ui.control.DropDown
        UnitsdemesureDropDown_2Label    matlab.ui.control.Label
        UnitsdemesureDropDown_2         matlab.ui.control.DropDown
        ResultatsTab                    matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        PerformancePanel                matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        TempsStabilisationLabel         matlab.ui.control.Label
        JustesseNALabel                 matlab.ui.control.Label
        PrecisionLabel                  matlab.ui.control.Label
        GraphiquesPanel                 matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        AxesMasseReelle                 matlab.ui.control.UIAxes
        Button                          matlab.ui.control.Button
        AxesMasseMesuree                matlab.ui.control.UIAxes
        AxesLame                        matlab.ui.control.UIAxes
        AxesCommande                    matlab.ui.control.UIAxes
        AffichagegraphiquePanel         matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        LCD                             matlab.ui.control.Label
        FichiersPanel                   matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        ChargerResultatsButton          matlab.ui.control.Button
        SauvegarderResultatsButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        x % Description
        DesignData % Data Dictionnary section for all model data (parameters)
        SimulationOutput % Description
        SimulationStopTime = 24; % Description
        MasseDeposee = 50 % La 1re masse déposée
        NouvelleMasseDeposee = 25 % La 2e masse deposée
        TempsNouvelleMasseDeposee = 16 % L'instant, en seconde, où la 2e masse est déposée
        CurrentSimulationOutputs % Description
    end
    
    methods (Access = private)
        function load_design_data(app, filepath)
            importFromFile(app.DesignData, filepath, 'existingVarsAction','overwrite');
            app.reset_parameters();
        end
        
        function load_default_design_data(app)
            app.load_design_data("default_design_data.mat")
        end
        
        function reset_parameters(app)
            app.GainEditField.Value = getValue(getEntry(app.DesignData, "K_amp"));
        end
        
        function UpdateResultats(app)
            plot(app.AxesMasseReelle, app.CurrentSimulationOutputs.masse_reelle(1, :), app.CurrentSimulationOutputs.masse_reelle(2, :))
            plot(app.AxesMasseMesuree, app.CurrentSimulationOutputs.masse_mesuree(1, :), app.CurrentSimulationOutputs.masse_mesuree(2, :))
            plot(app.AxesLame, app.CurrentSimulationOutputs.deflexion(1, :), app.CurrentSimulationOutputs.deflexion(2, :))
            plot(app.AxesCommande, app.CurrentSimulationOutputs.commande(1, :), app.CurrentSimulationOutputs.commande(2, :))

            app.TempsStabilisationLabel.Text = sprintf("Temps de stabilisation: %0.3f s", app.CurrentSimulationOutputs.T_stabilisation);
            app.PrecisionLabel.Text = sprintf("Précision: %0.4f g", app.CurrentSimulationOutputs.precision);
            app.JustesseNALabel.Text = sprintf("Justesse: %0.4f %%", app.CurrentSimulationOutputs.justesse * 100);
        end
        
        function FocusMainWindow(app)
            % Fixes bug (in App Designer?) that the main window becomes out
            % of focus after open a file through the file dialog.
            figure(app.BalanceCAOUIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dataDictionnary = Simulink.data.dictionary.open("dict.sldd");
            app.DesignData = getSection(dataDictionnary, "Design Data");
            app.load_default_design_data();
            
            % set_param("main", 'SimulationCommand', 'stop');
        end

        % Callback function
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            app.x = value;
            disp(app.x);
            setValue(getEntry(app.DesignData, "x"), value);
        end

        % Button pushed function: LancerlasimulationButton
        function LancerlasimulationButtonPushed(app, event)
            app.LancerlasimulationButton.Enable = "off";
            progressBar = uiprogressdlg(app.BalanceCAOUIFigure, "Title", "Simulation en cours...", "Indeterminate", "on");
            
            % variables temporaires de la simulation
            inputs = Simulink.SimulationInput("main");
            inputs = inputs.setVariable("masse_deposee", app.MasseDeposee, "Workspace", "main");
            if app.ChangementDeMasseCheckBox.Value == true
                inputs = inputs.setVariable("nouvelle_masse_deposee", app.NouvelleMasseDeposee, "Workspace", "main");
            else
                inputs = inputs.setVariable("nouvelle_masse_deposee", 0, "Workspace", "main");
            end
            inputs = inputs.setVariable("temps_nouvelle_masse_deposee", app.TempsNouvelleMasseDeposee, "Workspace", "main");
            
            set_param("main", 'StopTime', string(app.SimulationStopTime))
            simulationOutput = sim(inputs);
            
            masseReelle = simulationOutput.logsout.getElement("masse_reelle").Values;
            masseMesuree = simulationOutput.logsout.getElement("masse_mesuree").Values;
            deflexion = simulationOutput.logsout.getElement("deflexion").Values;
            commande = simulationOutput.logsout.getElement("commande").Values;
            app.CurrentSimulationOutputs = struct( ...
                'masse_reelle', [masseReelle.Time'; masseReelle.Data(:)'], ...
                'masse_mesuree', [masseMesuree.Time'; masseMesuree.Data(:)'], ...
                'deflexion', [deflexion.Time'; deflexion.Data(:)' .* 1000], ...
                'commande', [commande.Time'; commande.Data(:)'], ...
                "T_stabilisation", simulationOutput.T_stabilisation, ...
                "justesse", simulationOutput.justesse, ...
                "precision", simulationOutput.precision ...
            );
            app.UpdateResultats()
            app.MainTabGroup.SelectedTab = app.MainTabGroup.Children(2);
            
            close(progressBar)
            app.LancerlasimulationButton.Enable = "on";
            app.SauvegarderResultatsButton.Enable = "on";
        end

        % Button pushed function: SauvegarderButton
        function SauvegarderButtonPushed(app, event)
            [file, path] = uiputfile("*.mat", "Sauvegarder les paramètres du simulateur");
            app.FocusMainWindow();
            
            if ~isequal(file, 0)
                exportToFile(app.DesignData, fullfile(path, file));
            end
        end

        % Button pushed function: ChargerparamtrespersonnalissButton
        function ChargerparamtrespersonnalissButtonPushed(app, event)
            [file, path] = uigetfile("*.mat", "Charger les paramètres du simulateur");
            app.FocusMainWindow();
            
            if ~isequal(file, 0)
                importFromFile(app.DesignData, fullfile(path, file), 'existingVarsAction','overwrite')
            end
        end

        % Button pushed function: ChargerparamtrespardfautButton
        function ChargerparamtrespardfautButtonPushed(app, event)
            app.load_default_design_data()
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            value = app.GainEditField.Value;
            setValue(getEntry(app.DesignData, "K_amp"), value);
        end

        % Value changed function: DuredelasimulationsEditField_2
        function DuredelasimulationsEditField_2ValueChanged(app, event)
            value = app.DuredelasimulationsEditField_2.Value;
            app.SimulationStopTime = value;
        end

        % Value changed function: MassedposegEditField
        function MassedposegEditFieldValueChanged(app, event)
            value = app.MassedposegEditField.Value;
            app.MasseDeposee = value;
        end

        % Button pushed function: BalayageButton
        function BalayageButtonPushed(app, event)
            app.BalayageButton.Enable = "off";
            progressBar = uiprogressdlg(app.BalanceCAOUIFigure, "Title", "Balayage en cours...", "Message", ...
                "L'exécution des simulations va prendre quelques minutes", "Indeterminate", "on");
            
            balayage(app.ParametreBalayeDropDown.Value);
            
            close(progressBar)
            app.BalayageButton.Enable = "on";
        end

        % Value changed function: ChangementDeMasseCheckBox
        function ChangementDeMasseCheckBoxValueChanged(app, event)
            value = app.ChangementDeMasseCheckBox.Value;
            
            if value == true
                app.NouvelleMasseEditField.Enable = "on";
                app.MomentArriveeEditField.Enable = "on";
            else
                app.NouvelleMasseEditField.Enable = "off";
                app.MomentArriveeEditField.Enable = "off";
            end
        end

        % Value changed function: NouvelleMasseEditField
        function NouvelleMasseEditFieldValueChanged(app, event)
            value = app.NouvelleMasseEditField.Value;
            app.NouvelleMasseDeposee = value;
        end

        % Value changed function: MomentArriveeEditField
        function MomentArriveeEditFieldValueChanged(app, event)
            value = app.MomentArriveeEditField.Value;
            app.TempsNouvelleMasseDeposee = value;
        end

        % Button pushed function: SauvegarderResultatsButton
        function SauvegarderResultatsButtonPushed(app, event)
            [file, path] = uiputfile("*.mat", "Sauvegarder les résultats de la simulation");
            app.FocusMainWindow();
            
            if ~isequal(file, 0)
                simulationOutputs = app.CurrentSimulationOutputs;
                save(fullfile(path, file), "simulationOutputs");
            end
        end

        % Button pushed function: ChargerResultatsButton
        function ChargerResultatsButtonPushed(app, event)
            [file, path] = uigetfile("*.mat", "Charger les résultats de la simulation");
            app.FocusMainWindow();
            
            if ~isequal(file, 0)
                load(fullfile(path, file), "simulationOutputs")
                app.CurrentSimulationOutputs = simulationOutputs;
                app.UpdateResultats()
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BalanceCAOUIFigure = uifigure('Visible', 'off');
            app.BalanceCAOUIFigure.Name = 'Balance CAO';
            app.GridLayout = uigridlayout(app.BalanceCAOUIFigure);
            app.MainTabGroup = uitabgroup(app.GridLayout);
            app.EditeurdesimulationTab = uitab(app.MainTabGroup);
            app.EditeurdesimulationTab.Title = 'Éditeur de simulation';
            app.GridLayout2 = uigridlayout(app.EditeurdesimulationTab);
            app.ParamtresdusimulateurPanel = uipanel(app.GridLayout2);
            app.ParamtresdusimulateurPanel.Title = 'Paramètres du simulateur';
            app.GridLayout4 = uigridlayout(app.ParamtresdusimulateurPanel);
            app.ChargerparamtrespardfautButton = uibutton(app.GridLayout4, 'push');
            app.ChargerparamtrespardfautButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerparamtrespardfautButtonPushed, true);
            app.ChargerparamtrespardfautButton.Text = 'Charger paramètres par défaut';
            app.SauvegarderButton = uibutton(app.GridLayout4, 'push');
            app.SauvegarderButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegarderButtonPushed, true);
            app.SauvegarderButton.Text = 'Sauvegarder';
            app.ChargerparamtrespersonnalissButton = uibutton(app.GridLayout4, 'push');
            app.ChargerparamtrespersonnalissButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerparamtrespersonnalissButtonPushed, true);
            app.ChargerparamtrespersonnalissButton.Text = 'Charger paramètres personnalisés';
            app.AmplificateurdepuissanceLabel = uilabel(app.GridLayout4);
            app.AmplificateurdepuissanceLabel.Text = 'Amplificateur de puissance';
            app.GainEditFieldLabel = uilabel(app.GridLayout4);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.EntresPanel = uipanel(app.GridLayout2);
            app.EntresPanel.Title = 'Entrées';
            app.GridLayout5 = uigridlayout(app.EntresPanel);
            app.MassedposegEditFieldLabel = uilabel(app.GridLayout5);
            app.MassedposegEditFieldLabel.Text = 'Masse déposée (g)';
            app.MassedposegEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MassedposegEditField.ValueChangedFcn = createCallbackFcn(app, @MassedposegEditFieldValueChanged, true);
            app.MassedposegEditField.Value = 50;
            app.ChangementDeMasseCheckBox = uicheckbox(app.GridLayout5);
            app.ChangementDeMasseCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangementDeMasseCheckBoxValueChanged, true);
            app.ChangementDeMasseCheckBox.Text = 'Ajout d''une seconde masse';
            app.NouvellemassegEditFieldLabel = uilabel(app.GridLayout5);
            app.NouvellemassegEditFieldLabel.Text = 'Nouvelle masse (g)';
            app.NouvelleMasseEditField = uieditfield(app.GridLayout5, 'numeric');
            app.NouvelleMasseEditField.ValueChangedFcn = createCallbackFcn(app, @NouvelleMasseEditFieldValueChanged, true);
            app.NouvelleMasseEditField.Enable = 'off';
            app.NouvelleMasseEditField.Value = 25;
            app.MomentdarrivedelamassesEditFieldLabel = uilabel(app.GridLayout5);
            app.MomentdarrivedelamassesEditFieldLabel.Text = 'Moment d''arrivée de la masse (s)';
            app.MomentArriveeEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MomentArriveeEditField.ValueChangedFcn = createCallbackFcn(app, @MomentArriveeEditFieldValueChanged, true);
            app.MomentArriveeEditField.Enable = 'off';
            app.MomentArriveeEditField.Value = 16;
            app.SimulationPanel = uipanel(app.GridLayout2);
            app.SimulationPanel.Title = 'Simulation';
            app.GridLayout3 = uigridlayout(app.SimulationPanel);
            app.LancerlasimulationButton = uibutton(app.GridLayout3, 'push');
            app.LancerlasimulationButton.ButtonPushedFcn = createCallbackFcn(app, @LancerlasimulationButtonPushed, true);
            app.LancerlasimulationButton.Text = 'Lancer la simulation';
            app.DuredelasimulationsEditField_2Label = uilabel(app.GridLayout3);
            app.DuredelasimulationsEditField_2Label.Text = 'Durée de la simulation (s)';
            app.DuredelasimulationsEditField_2 = uieditfield(app.GridLayout3, 'text');
            app.DuredelasimulationsEditField_2.ValueChangedFcn = createCallbackFcn(app, @DuredelasimulationsEditField_2ValueChanged, true);
            app.DuredelasimulationsEditField_2.Value = '24';
            app.BalayagedeparamtresPanel = uipanel(app.GridLayout2);
            app.BalayagedeparamtresPanel.Title = 'Balayage de paramètres';
            app.GridLayout6 = uigridlayout(app.BalayagedeparamtresPanel);
            app.ParamtrebalayDropDown_2Label = uilabel(app.GridLayout6);
            app.ParamtrebalayDropDown_2Label.Text = 'Paramètre balayé';
            app.ParametreBalayeDropDown = uidropdown(app.GridLayout6);
            app.ParametreBalayeDropDown.Items = {'dac_commande_resolution', 'N_bobine'};
            app.ParametreBalayeDropDown.Value = 'dac_commande_resolution';
            app.BalayageButton = uibutton(app.GridLayout6, 'push');
            app.BalayageButton.ButtonPushedFcn = createCallbackFcn(app, @BalayageButtonPushed, true);
            app.BalayageButton.Text = 'Balayer un paramètre';
            app.OptionsdaffichagePanel = uipanel(app.GridLayout2);
            app.OptionsdaffichagePanel.Title = 'Options d''affichage';
            app.GridLayout7 = uigridlayout(app.OptionsdaffichagePanel);
            app.ModeDropDown_2Label = uilabel(app.GridLayout7);
            app.ModeDropDown_2Label.Text = 'Mode';
            app.ModeDropDown_2 = uidropdown(app.GridLayout7);
            app.ModeDropDown_2.Items = {'Masse totale', 'Comptage de pièces'};
            app.ModeDropDown_2.Value = 'Masse totale';
            app.UnitsdemesureDropDown_2Label = uilabel(app.GridLayout7);
            app.UnitsdemesureDropDown_2Label.Text = 'Unités de mesure';
            app.UnitsdemesureDropDown_2 = uidropdown(app.GridLayout7);
            app.UnitsdemesureDropDown_2.Items = {'grammes', 'onces'};
            app.UnitsdemesureDropDown_2.Value = 'grammes';
            app.ResultatsTab = uitab(app.MainTabGroup);
            app.ResultatsTab.Title = 'Résultats';
            app.GridLayout8 = uigridlayout(app.ResultatsTab);
            app.PerformancePanel = uipanel(app.GridLayout8);
            app.PerformancePanel.Title = 'Performance';
            app.GridLayout11 = uigridlayout(app.PerformancePanel);
            app.TempsStabilisationLabel = uilabel(app.GridLayout11);
            app.TempsStabilisationLabel.Text = 'Temps de stabilisation: N/A';
            app.JustesseNALabel = uilabel(app.GridLayout11);
            app.JustesseNALabel.Text = 'Justesse: N/A';
            app.PrecisionLabel = uilabel(app.GridLayout11);
            app.PrecisionLabel.Text = 'Précision: N/A';
            app.GraphiquesPanel = uipanel(app.GridLayout8);
            app.GraphiquesPanel.Title = 'Graphiques';
            app.GridLayout9 = uigridlayout(app.GraphiquesPanel);
            app.AxesMasseReelle = uiaxes(app.GridLayout9);
            title(app.AxesMasseReelle, 'Masse réelle')
            xlabel(app.AxesMasseReelle, 'Temps (s)')
            ylabel(app.AxesMasseReelle, 'Masse (g)')
            app.Button = uibutton(app.GridLayout9, 'push');
            app.AxesMasseMesuree = uiaxes(app.GridLayout9);
            title(app.AxesMasseMesuree, 'Masse mesurée')
            xlabel(app.AxesMasseMesuree, 'Temps (s)')
            ylabel(app.AxesMasseMesuree, 'Masse (g)')
            app.AxesLame = uiaxes(app.GridLayout9);
            title(app.AxesLame, 'Lame')
            xlabel(app.AxesLame, 'Temps (s)')
            ylabel(app.AxesLame, 'Délexion (mm)')
            app.AxesCommande = uiaxes(app.GridLayout9);
            title(app.AxesCommande, 'Commande')
            xlabel(app.AxesCommande, 'Temps (s)')
            ylabel(app.AxesCommande, 'Commande (V)')
            app.AffichagegraphiquePanel = uipanel(app.GridLayout8);
            app.AffichagegraphiquePanel.Title = 'Affichage graphique';
            app.GridLayout10 = uigridlayout(app.AffichagegraphiquePanel);
            app.LCD = uilabel(app.GridLayout10);
            app.LCD.Text = 'N/A';
            app.FichiersPanel = uipanel(app.GridLayout8);
            app.FichiersPanel.Title = 'Fichiers';
            app.GridLayout12 = uigridlayout(app.FichiersPanel);
            app.ChargerResultatsButton = uibutton(app.GridLayout12, 'push');
            app.ChargerResultatsButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerResultatsButtonPushed, true);
            app.ChargerResultatsButton.Text = 'Charger des résultats';
            app.SauvegarderResultatsButton = uibutton(app.GridLayout12, 'push');
            app.SauvegarderResultatsButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegarderResultatsButtonPushed, true);
            app.SauvegarderResultatsButton.Enable = 'off';
            app.SauvegarderResultatsButton.Text = 'Sauvegarder des résultats';
            % Show the figure after all components are created
            app.BalanceCAOUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BalanceCAOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BalanceCAOUIFigure)
        end
    end
end